classdef hf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        OpenfileEditFieldLabel  matlab.ui.control.Label
        OpenfileEditField       matlab.ui.control.EditField
        OpenButton              matlab.ui.control.Button
        BrowseButton            matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
        file % the name of opened file
        path % path to file
        data % nmea data
        drawables = zeros(35, 3) % Description
        s_drawables = 0;
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            plotline = polarplot(app.Pax, [10 5], [10 15], 'xb');
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.RLim = [0 500];
            app.Pax.Position = [100, 100, 230, 230];
            
            % Plot the polar function
            %updatePlot(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %text=fopen(app.OpenfileEditField.Value);
            %app.OpenfileEditField.Value=fgets(text);
            if app.OpenfileEditField.Value ~= ""
                dialog_box = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Indeterminate','on');
                dialog_box.Message = 'Loading NMEA data...';
                app.data = readNMEA(app.OpenfileEditField.Value, "$GPGSV");
                dialog_box.Value = 0.5;
                pause(1);
                close(dialog_box);
                %sat1 = [data{1, 6} data{1, 7}]
                %sat2 = [data{1, 10} data{1, 11}]
                %data=data(data(:,1)==29,:);
                %for i=1 : size(data, 1)
                    %polarplot(app.Pax, data(:, 2), data(:, 3), 'xb');
                    %pause(1);
                    %dialog_box.Value = 0.5 + (0.5 / size(data, 1)*(i-1));
                %end
                
                
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1);
            [app.file, app.path] = uigetfile('*.txt');
            if isequal(app.file, 0)

            else
                app.OpenfileEditField.Value=strcat(app.path, app.file);
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.Label.Text = '0%';
            for i = 1:size(app.data, 1)
                if app.data(i,1) == 0
                    app.Label.Text = num2str(i/size(app.data, 1)*100) + "%";
                    polarplot(app.Pax, app.drawables(1:app.s_drawables, 2), app.drawables(1:app.s_drawables, 3), 'ob', 'MarkerSize', 20);
                    text(app.Pax, app.drawables(1:app.s_drawables, 2), app.drawables(1:app.s_drawables, 3), num2str(app.drawables(1:app.s_drawables, 1)), 'VerticalAlignment','middle','HorizontalAlignment','center');
                    drawnow;
                    %pause(1);
                    app.drawables = zeros(35, 3);
                    app.s_drawables = 0;
                    %clf(app.Pax);
                    cla(app.Pax);
                else
                    app.drawables(app.s_drawables+1,:) = app.data(i,:);
                    app.s_drawables = app.s_drawables + 1;
                    %polarplot(app.Pax, app.data(i, 2), app.data(i, 3), 'xb');
                    %app.Pax.RLim = [0 500];
                    %drawnow;
                    %hold(app.Pax, 'on');
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.OpenfileEditFieldLabel = uilabel(app.Panel);
            app.OpenfileEditFieldLabel.Text = 'Open file:';
            app.OpenfileEditField = uieditfield(app.Panel, 'text');
            app.OpenfileEditField.Value = 'D:\Documents\BME_KJK_MSc\1_semester\localization_and_mapping\lam-hf\L\nmea2.txt';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hf

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end